classdef BeadMapperSimple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeadMapperSimpleUIFigure        matlab.ui.Figure
        OptionsPanel                    matlab.ui.container.Panel
        EllipticityCutoffEditField      matlab.ui.control.NumericEditField
        EllipticityCutoffEditFieldLabel  matlab.ui.control.Label
        CircularityCutoffEditField      matlab.ui.control.NumericEditField
        CircularityCutoffEditFieldLabel  matlab.ui.control.Label
        SpotFindingThresholdEditField   matlab.ui.control.NumericEditField
        SpotFindingThresholdEditFieldLabel  matlab.ui.control.Label
        EdgePaddingPixelsEditField      matlab.ui.control.NumericEditField
        EdgePaddingPixelsEditFieldLabel  matlab.ui.control.Label
        DistanceCutoffPixelsEditField   matlab.ui.control.NumericEditField
        DistanceCutoffPixelsEditFieldLabel  matlab.ui.control.Label
        SpotFindingMethodDropDown       matlab.ui.control.DropDown
        SpotFindingMethodDropDownLabel  matlab.ui.control.Label
        GetBeadMapButton                matlab.ui.control.Button
        SaveBeadMapFileButton           matlab.ui.control.Button
        DonorAndAcceptorCombined        matlab.ui.control.CheckBox
        AcceptorFileName                matlab.ui.control.EditField
        DonorFileName                   matlab.ui.control.EditField
        AcceptorBeadImageLabel          matlab.ui.control.Label
        DonorBeadImageLabel             matlab.ui.control.Label
        LogBox                          matlab.ui.control.TextArea
        LogTextAreaLabel                matlab.ui.control.Label
        ChooseAcceptorButton            matlab.ui.control.Button
        ChooseDonorButton               matlab.ui.control.Button
        DonorAcceptorRegisteredLabel    matlab.ui.control.Label
        DonorAcceptorOriginalLabel      matlab.ui.control.Label
        OverlayCorrected                matlab.ui.control.UIAxes
        OverlayOriginal                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Version = '1.0.2-alpha'; % On build, remember to update Version under Sharing Details
        Options = struct; % Analysis options
        Results = struct; % Analysis results
        SpotMethod = 'Centroid'; % Spot finding method; 'Gaussian' (more accurate) or 'Centroid' (faster)
        DistanceCutoff = 2; % In pixels; cutoff for considering beads in separate channels to be matched
        EdgePixels = 10; % Number of pixels to ignore at edges of field of view
        Threshold = 10; % Spot finding threshold
        CircularityCutoff = 0.9; % Minimum spot circularity (0-1 scale) for Centroid fitting
        EllipticityCutoff = 1.5; % Maximum spot ellipticity (1=circular) for Gaussian fitting
        OriginalAxesStartingPosition
        RegisteredAxesStartingPosition
        Log = cell(0,1); % Log of operations and errors
    end
    
    methods (Access = private)       
        
        function showChannelOverlay(app,Image1,Image2,Args)
            arguments
                app
                Image1
                Image2
                Args.Axes
            end
            I = zeros(height(Image1),width(Image1),3);
            I(:,:,1) = double((Image2 - median(Image2(:)))/(quantile(Image2(:),0.998)-median(Image2(:)))); % Acceptor channel
            I(:,:,2) = double((Image1 - median(Image1(:)))/(quantile(Image1(:),0.998)-median(Image1(:)))); % Donor channel
            imshow(I,[min(I(:)),max(I(:))/10],'Parent',Args.Axes);

            % Set aspect ratio and position of axes to match image dimensions
            AspectRatio = width(Image1)/height(Image1);
            Args.Axes.PlotBoxAspectRatio(1) = AspectRatio;
            if Args.Axes == app.OverlayOriginal
                AxesPosition = app.OriginalAxesStartingPosition;
            else
                AxesPosition = app.RegisteredAxesStartingPosition;
            end
            NewWidth = AxesPosition(4)*AspectRatio;
            NewXPosition = AxesPosition(1)+(AxesPosition(4)-NewWidth)/2;
            Args.Axes.Position = [NewXPosition AxesPosition(2) NewWidth AxesPosition(4)];
            xlim(Args.Axes, [0 width(Image1)]);
            ylim(Args.Axes, [0 height(Image1)]);
        end
        
        function plotSpotOverlay(app, Coords1, Coords2, AxesHandle)
            arguments
                app
                Coords1 double
                Coords2 double
                AxesHandle
            end
            hold(AxesHandle, 'on');
            plot(Coords1(:,1),Coords1(:,2),'gs','Parent',AxesHandle);
            plot(Coords2(:,1),Coords2(:,2),'ro','Parent',AxesHandle);
            hold(AxesHandle, 'off');
        end

        function getOptions(app)
            % Set properties of app according to options selected in UI window
            app.EdgePixels = app.EdgePaddingPixelsEditField.Value;
            app.Threshold = app.SpotFindingThresholdEditField.Value;
            app.CircularityCutoff = app.CircularityCutoffEditField.Value;
            app.EllipticityCutoff = app.EllipticityCutoffEditField.Value;
            app.SpotMethod = app.SpotFindingMethodDropDown.Value;
        end
        
        function Options = packageOptions(app)
            Options.SpotMethod = app.SpotMethod;
            Options.EdgePixels = app.EdgePixels;
            Options.Threshold = app.Threshold;
            Options.CircularityCutoff = app.CircularityCutoff;
            Options.EllipticityCutoff = app.EllipticityCutoff;
        end
        
        function exportLog(app,FileName)
            LogToSave = app.Log;
            fid = fopen(FileName,'w');
            for n = 1:numel(LogToSave)
                fwrite(fid,LogToSave(n));
                fwrite(fid,newline);
            end
            fclose(fid);
        end
        
        function logError(app,Exception)
            arguments
                app
                Exception MException;
            end
            addLogText(app,getReport(Exception,'extended','hyperlinks','off'),'FileOnly',true);
            Path = strcat(userpath,filesep,'BeadMapperSimple',filesep,'ErrorLogs',filesep);
            if ~exist(Path,"dir")
                mkdir(Path);
            end
            File = strcat('Error_',string(datetime('now','Format','yyyy-MM-dd_HHmmmss')),'.txt');
            FileName = fullfile(Path,File);
            exportLog(app,FileName);
            addLogText(app,sprintf('An error occurred.  Saved error log to %s',FileName));
        end
    end
    
    methods (Access = public)
         function addLogText(app,Text,Args)
            % Adds text to log on UI window
            arguments
                app
                Text % Text = string, char, cell array, or struct of text to add to log printout
                Args.NewLine = true; % specifying whether a new line is added in the log
                Args.FileOnly = false;
            end
            if isstring(Text) || ischar(Text)
                Text = {Text};
            elseif isstruct(Text)
                Fields = fieldnames(Text);
                N = numel(Fields);
                TextStruct = Text; Text = cell(N,1);
                for n = 1:numel(Fields)
                    Text{n} = strcat(Fields{n},': ',string(TextStruct.(Fields{n})));
                end
            end
            for n = 1:numel(Text)
                if Args.NewLine
                    TextToAdd = sprintf("> %s",Text{n});
                    if ~Args.FileOnly
                        app.LogBox.Value = [TextToAdd; app.LogBox.Value];
                    end
                    app.Log = [app.Log; TextToAdd];
                else
                    if ~Args.FileOnly
                        app.LogBox.Value{1} = strcat(app.LogBox.Value{1},Text{n});
                    end
                    app.Log{end} = strcat(app.Log{end}, Text{n});
                end
            end
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addLogText(app,{'Bead Mapper Simple';['Version: ', app.Version]});
            % Show black images to start
            Idummy = zeros(1024,1024);
            xlim(app.OverlayOriginal, [0.5, size(Idummy, 2)+0.5]);
            ylim(app.OverlayOriginal, [0.5, size(Idummy, 1)+0.5]);
            xlim(app.OverlayCorrected, [0.5, size(Idummy, 2)+0.5]);
            ylim(app.OverlayCorrected, [0.5, size(Idummy, 1)+0.5]);
            hold(app.OverlayOriginal, 'on');
            axis(app.OverlayOriginal, 'manual');  % Prevent auto axis resizing
            hold(app.OverlayCorrected, 'on');
            axis(app.OverlayCorrected, 'manual');  % Prevent auto axis resizing
            h = imshow(Idummy,'Parent',app.OverlayOriginal);
            j = imshow(Idummy,'Parent',app.OverlayCorrected);
            % Startup prompt in log box
            addLogText(app,strcat('Current date and time: ',string(datetime)));
            addLogText(app,'Choose a donor and acceptor image file, then hit "Get Bead Map."');
            app.OriginalAxesStartingPosition = app.OverlayOriginal.Position;
            app.RegisteredAxesStartingPosition = app.OverlayCorrected.Position;
            h.PickableParts = 'none';   % ignore mouse hover/click for data tips
            j.PickableParts = 'none';
        end

        % Button pushed function: ChooseDonorButton
        function ChooseDonorButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.tif', 'Select donor file (or combined donor + acceptor file)');
            app.DonorFileName.Value = fullfile(PathName,FileName);
            addLogText(app,'Donor bead image selected.');
            cd(PathName);
        end

        % Button pushed function: ChooseAcceptorButton
        function ChooseAcceptorButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.tif','Select acceptor file');
            app.AcceptorFileName.Value = fullfile(PathName,FileName);
            addLogText(app,'Acceptor bead image selected.');
        end

        % Button pushed function: GetBeadMapButton
        function GetBeadMapButtonPushed(app, event)
            try
            addLogText(app,'Get Bead Map Button Pushed','FileOnly',true);
            
            % Check file names
            if isempty(app.DonorFileName.Value)
                errordlg('No donor file selected!  Select a donor image and try again.');
                return
            elseif isempty(app.AcceptorFileName.Value) && ~app.DonorAndAcceptorCombined.Value
                errordlg('No acceptor file selected!  Please select an acceptor image, or check "Donor and Acceptor in Same Image."');            
                return
            end

            addLogText(app,'Starting analysis...');
            getOptions(app);
            app.Options = packageOptions(app);
            addLogText(app,app.Options,'FileOnly',true);

            addLogText(app,'Loading movies...');

            AvgImage = loadBeadImages(app.DonorFileName.Value,app.AcceptorFileName.Value);

            % Find rough translational offset between images
            addLogText(app,'Getting initial translational offset...');
            TranslationalOffset = findTranslationalOffset(AvgImage);
            AvgImage.Movie2Translated = applyTranslationalOffset(AvgImage.Movie2,TranslationalOffset);
            
            % Get pairs of bead locations and remove translational offset to match original acceptor channel
            addLogText(app,'Finding bead pairs...');
            % Find beads in image 1, image 2, and translated image 2
            Coords = findSpotsAllMovies(app, AvgImage, app.Options);
            SpotPairs = getSpotPairs(Coords.Movie1,Coords.Movie2Translated,app.DistanceCutoff);
            SpotPairs.Coords2 = cat(2,SpotPairs.Coords2(:,1)+TranslationalOffset.X,SpotPairs.Coords2(:,2)+TranslationalOffset.Y);
            
            %Show starting images and bead locations
            showChannelOverlay(app,AvgImage.Movie1, AvgImage.Movie2,'Axes', app.OverlayOriginal);
            plotSpotOverlay(app, Coords.Movie1, Coords.Movie2,app.OverlayOriginal);

            % Construct and apply transformation to acceptor channel image
            addLogText(app,'Getting stage 2 (affine) transformation...');
            TformInitial = fitgeotform2d(SpotPairs.Coords2,SpotPairs.Coords1,'affine');
            TformInitialInv = fitgeotform2d(SpotPairs.Coords1,SpotPairs.Coords2,'affine');
            Coords.Movie2Affine = transformPointsInverse(TformInitialInv,Coords.Movie2);
            
            % Get pairs of bead locations with initial affine transform and apply inverse transform to recover original acceptor coords
            addLogText(app,'Finding bead pairs with stage 2 transformation...');
            SpotPairs = getSpotPairs(Coords.Movie1,Coords.Movie2Affine,app.DistanceCutoff);
            SpotPairs.Coords2 = transformPointsInverse(TformInitial,SpotPairs.Coords2);

            % Construct and apply transformation to acceptor channel image
            addLogText(app,'Getting final (3rd order polynomial) transformation...');
            app.Results.Tform = makePoly3Transform(SpotPairs.Coords1,SpotPairs.Coords2);
            Coords.Movie2Corrected = applyPoly3Transform(app.Results.Tform,Coords.Movie2,'Reverse');
            SpotPairsCorrected = getSpotPairs(Coords.Movie1,Coords.Movie2Corrected,app.DistanceCutoff);
            SpotPairsCorrected.Coords2Reversed = applyPoly3Transform(app.Results.Tform,SpotPairsCorrected.Coords2,'Forward');

            % Calculate localization error between donor + original/registered acceptor channel; 
            % place results in app.Results.InitialError and .FinalError
            [app.Results, LogText] = calculateError(SpotPairs,SpotPairsCorrected,app.Results);
            addLogText(app,LogText);
            
            %Show image overlay with final registered points
            showChannelOverlay(app, AvgImage.Movie1, AvgImage.Movie2, 'Axes', app.OverlayCorrected);
            plotSpotOverlay(app, SpotPairsCorrected.Coords1, SpotPairsCorrected.Coords2Reversed, app.OverlayCorrected);

            % Enable button to save bead map file
            app.SaveBeadMapFileButton.Enable = "on";

            catch ME
                logError(app,ME);
            end
        end

        % Value changed function: DonorAndAcceptorCombined
        function DonorAndAcceptorCombinedValueChanged(app, event)
            value = app.DonorAndAcceptorCombined.Value;
            if value
                addLogText(app,'Donor and Acceptor in Same Image Checkbox Ticked','FileOnly',true);
                app.ChooseAcceptorButton.Enable = "off";
                app.AcceptorFileName.Value = "";
                app.AcceptorFileName.Enable = "off";
            else
                addLogText(app,'Donor and Acceptor in Same Image Checkbox Unticked','FileOnly',true);
                app.ChooseAcceptorButton.Enable = "on";
                app.AcceptorFileName.Enable = "on";
            end
        end

        % Button pushed function: SaveBeadMapFileButton
        function SaveBeadMapFileButtonPushed(app, event)
            try
            addLogText(app,'Save Bead Map Button Pushed','FileOnly',true);

            if isfield(app.Results,'Tform')
                FullModel = app.Results.Tform.Fullmodel;
            else
                errordlg('No bead map transform available!  Please run a bead mapping and try again.');
                return
            end

            [File, Path] = uiputfile('*.txt','Select where to save your map file.','Bead_mapped.txt');
            [~, BaseName, ~] = fileparts(File);

            if exist(fullfile(Path),"file")
                save(fullfile(Path,File),'FullModel','-ascii');
                exportLog(app,fullfile(Path,strcat(BaseName,'_analysis_log.txt')));
                addLogText(app,['Saved bead map file to ',fullfile(Path,File)]);
            end
            catch ME
                logError(app,ME);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeadMapperSimpleUIFigure = uifigure('Visible', 'off');
            app.BeadMapperSimpleUIFigure.Name = 'Bead Mapper Simple';
            app.OverlayOriginal = uiaxes(app.BeadMapperSimpleUIFigure);
            zlabel(app.OverlayOriginal, 'Z')
            app.OverlayCorrected = uiaxes(app.BeadMapperSimpleUIFigure);
            zlabel(app.OverlayCorrected, 'Z')
            app.OverlayCorrected.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DonorAcceptorOriginalLabel = uilabel(app.BeadMapperSimpleUIFigure);
            app.DonorAcceptorOriginalLabel.Text = 'Donor + Acceptor (Original)';
            app.DonorAcceptorRegisteredLabel = uilabel(app.BeadMapperSimpleUIFigure);
            app.DonorAcceptorRegisteredLabel.Text = 'Donor + Acceptor (Registered)';
            app.ChooseDonorButton = uibutton(app.BeadMapperSimpleUIFigure, 'push');
            app.ChooseDonorButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDonorButtonPushed, true);
            app.ChooseDonorButton.Text = 'Choose...';
            app.ChooseAcceptorButton = uibutton(app.BeadMapperSimpleUIFigure, 'push');
            app.ChooseAcceptorButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseAcceptorButtonPushed, true);
            app.ChooseAcceptorButton.Text = 'Choose...';
            app.LogTextAreaLabel = uilabel(app.BeadMapperSimpleUIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogBox = uitextarea(app.BeadMapperSimpleUIFigure);
            app.DonorBeadImageLabel = uilabel(app.BeadMapperSimpleUIFigure);
            app.DonorBeadImageLabel.Tooltip = {'If using a movie with Donor + Acceptor combined, load it as Donor Bead File'};
            app.DonorBeadImageLabel.Text = 'Donor Bead Image';
            app.AcceptorBeadImageLabel = uilabel(app.BeadMapperSimpleUIFigure);
            app.AcceptorBeadImageLabel.Tooltip = {'If using a single TIF file with both Donor and Acceptor, LEAVE THIS BLANK'};
            app.AcceptorBeadImageLabel.Text = 'Acceptor Bead Image';
            app.DonorFileName = uieditfield(app.BeadMapperSimpleUIFigure, 'text');
            app.AcceptorFileName = uieditfield(app.BeadMapperSimpleUIFigure, 'text');
            app.DonorAndAcceptorCombined = uicheckbox(app.BeadMapperSimpleUIFigure);
            app.DonorAndAcceptorCombined.ValueChangedFcn = createCallbackFcn(app, @DonorAndAcceptorCombinedValueChanged, true);
            app.DonorAndAcceptorCombined.Tooltip = {'Check this box if you have a single TIF containing both donor (left) and acceptor (right)channels'};
            app.DonorAndAcceptorCombined.Text = 'Donor and Acceptor in Same Image';
            app.SaveBeadMapFileButton = uibutton(app.BeadMapperSimpleUIFigure, 'push');
            app.SaveBeadMapFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBeadMapFileButtonPushed, true);
            app.SaveBeadMapFileButton.Enable = 'off';
            app.SaveBeadMapFileButton.Text = 'Save Bead Map File';
            app.GetBeadMapButton = uibutton(app.BeadMapperSimpleUIFigure, 'push');
            app.GetBeadMapButton.ButtonPushedFcn = createCallbackFcn(app, @GetBeadMapButtonPushed, true);
            app.GetBeadMapButton.Text = 'Get Bead Map';
            app.OptionsPanel = uipanel(app.BeadMapperSimpleUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.SpotFindingMethodDropDownLabel = uilabel(app.OptionsPanel);
            app.SpotFindingMethodDropDownLabel.Text = 'Spot Finding Method';
            app.SpotFindingMethodDropDown = uidropdown(app.OptionsPanel);
            app.SpotFindingMethodDropDown.Items = {'Gaussian', 'Centroid'};
            app.SpotFindingMethodDropDown.Tooltip = {'Centroid (default): Much faster'; 'Gaussian: Slightly more accurate'};
            app.SpotFindingMethodDropDown.Value = 'Centroid';
            app.DistanceCutoffPixelsEditFieldLabel = uilabel(app.OptionsPanel);
            app.DistanceCutoffPixelsEditFieldLabel.Text = 'Distance Cutoff (Pixels)';
            app.DistanceCutoffPixelsEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.DistanceCutoffPixelsEditField.Tooltip = {'In pixels; maximum distance between two beads to consider them paired'};
            app.DistanceCutoffPixelsEditField.Value = 2;
            app.EdgePaddingPixelsEditFieldLabel = uilabel(app.OptionsPanel);
            app.EdgePaddingPixelsEditFieldLabel.Text = 'Edge Padding (Pixels)';
            app.EdgePaddingPixelsEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.EdgePaddingPixelsEditField.Tooltip = {'Number of pixels to ignore at edges of field of view'};
            app.EdgePaddingPixelsEditField.Value = 10;
            app.SpotFindingThresholdEditFieldLabel = uilabel(app.OptionsPanel);
            app.SpotFindingThresholdEditFieldLabel.Text = 'Spot Finding Threshold';
            app.SpotFindingThresholdEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.SpotFindingThresholdEditField.Tooltip = {'Higher threshold = fewer, brighter spots found'};
            app.SpotFindingThresholdEditField.Value = 10;
            app.CircularityCutoffEditFieldLabel = uilabel(app.OptionsPanel);
            app.CircularityCutoffEditFieldLabel.Text = 'Circularity Cutoff';
            app.CircularityCutoffEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.CircularityCutoffEditField.Tooltip = {'Minimum spot circularity (0-1 scale) for Centroid fitting'};
            app.CircularityCutoffEditField.Value = 0.9;
            app.EllipticityCutoffEditFieldLabel = uilabel(app.OptionsPanel);
            app.EllipticityCutoffEditFieldLabel.Text = 'Ellipticity Cutoff';
            app.EllipticityCutoffEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.EllipticityCutoffEditField.Tooltip = {'Maximum spot ellipticity (1=circular) for Gaussian fitting'};
            app.EllipticityCutoffEditField.Value = 1.5;
            % Show the figure after all components are created
            app.BeadMapperSimpleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeadMapperSimple

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BeadMapperSimpleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeadMapperSimpleUIFigure)
        end
    end
end