classdef BeadMapperSimple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ChooseDonorButton_3           matlab.ui.control.Button
        GetBeadMapButton              matlab.ui.control.Button
        DonorAndAcceptorCombined      matlab.ui.control.CheckBox
        AcceptorFileName              matlab.ui.control.EditField
        DonorFileName                 matlab.ui.control.EditField
        AcceptorBeadImageLabel        matlab.ui.control.Label
        DonorBeadImageLabel           matlab.ui.control.Label
        LogBox                        matlab.ui.control.TextArea
        LogTextAreaLabel              matlab.ui.control.Label
        ChooseAcceptorButton          matlab.ui.control.Button
        ChooseDonorButton             matlab.ui.control.Button
        DonorAcceptorRegisteredLabel  matlab.ui.control.Label
        DonorAcceptorOriginalLabel    matlab.ui.control.Label
        OverlayCorrected              matlab.ui.control.UIAxes
        OverlayOriginal               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Results = struct; % Description
    end
    
    methods (Access = private)       
        function addLogText(app,Text,varargin)
            % Adds text to log on UI window
            % Args:
            % Text = string or char of text to add to log printout
            % varargin{1}: NewLine = optional boolean specifying whether a new line
            % is added in the log.  Default = true
            if nargin>2
                NewLine = varargin{1};
            else
                NewLine = true;
            end
            if NewLine
                TextToAdd = sprintf("> %s",Text);
                app.LogBox.Value = [TextToAdd; app.LogBox.Value];
            else
                app.LogBox.Value{1} = strcat(app.LogBox.Value{1},Text);
            end
        end
        
        function AvgImage = loadBeadImages(app,Args)
            arguments
                Args.MovieFile1 {mustBeText} = '';
                Args.MovieFile2 {mustBeText} = '';
            end
            % Load movies from file and generate temporally averaged images
            fprintf('Loading movies...\n');
            AvgImage = loadBeadImages(Args.MovieFile1,Args.MovieFile2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Show black images to start
            Idummy = zeros(1024,1024);
            xlim(app.OverlayOriginal, [0.5, size(Idummy, 2)+0.5]);
            ylim(app.OverlayOriginal, [0.5, size(Idummy, 1)+0.5]);
            xlim(app.OverlayCorrected, [0.5, size(Idummy, 2)+0.5]);
            ylim(app.OverlayCorrected, [0.5, size(Idummy, 1)+0.5]);
            hold(app.OverlayOriginal, 'on');
            axis(app.OverlayOriginal, 'manual');  % Prevent auto axis resizing
            hold(app.OverlayCorrected, 'on');
            axis(app.OverlayCorrected, 'manual');  % Prevent auto axis resizing
            imshow(Idummy,'Parent',app.OverlayOriginal);
            imshow(Idummy,'Parent',app.OverlayCorrected);
            % Startup prompt in log box
            addLogText(app,'Choose a donor and acceptor image file, then hit "Get Bead Map."','NewLine');

        end

        % Button pushed function: ChooseDonorButton
        function ChooseDonorButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.tif', 'Select donor file (or combined donor + acceptor file)');
            app.DonorFileName.Value = fullfile(PathName,FileName);
            addLogText(app,'Donor bead image selected.','NewLine');
            cd(PathName);
        end

        % Button pushed function: ChooseAcceptorButton
        function ChooseAcceptorButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.tif','Select acceptor file');
            app.AcceptorFileName.Value = fullfile(PathName,FileName);
            addLogText(app,'Acceptor bead image selected.','NewLine');
        end

        % Button pushed function: GetBeadMapButton
        function GetBeadMapButtonPushed(app, event)
            % Check file names
            if isempty(app.DonorFileName.Value)
                errormsg('No donor file selected!  Select a donor image and try again.');
                return
            elseif isempty(app.AcceptorFileName.Value) && app.DonorAndAcceptorCombined.Value
                errormsg('No acceptor file selected!  Please select an acceptor image, or check "Donor and Acceptor in Same Image."');            
                return
            end
            addLogText(app,'Starting analysis...','NewLine');
            addLogText(app,'Loading movies...','NewLine');
            AvgImage = loadBeadImages(Args.MovieFile1,Args.MovieFile2);
            
            % Parameters
            Threshold = 15;
            CircularityCutoff = 0.95;
            SpotMethod = 'Gaussian';

            % Find rough translational offset between images
            addLogText(app,'Getting initial translational offset...','NewLine');
            TranslationalOffset = findTranslationalOffset(AvgImage);
            AvgImage.Movie2Translated = applyTranslationalOffset(AvgImage.Movie2,TranslationalOffset);
            
            addLogText(app,'Finding bead pairs...','NewLine');
            % Find beads in both images
            Coords.Movie1 = findSpots(AvgImage.Movie1,...
                                        10,...
                                        Threshold,...
                                        CircularityCutoff,...
                                        'Gaussian');
            Coords.Movie2Translated = findSpots(AvgImage.Movie2Translated,...
                                        10,...
                                        Threshold,...
                                        CircularityCutoff,...
                                        'Gaussian');
            
            % Get pairs of bead locations and remove translational offset to match original acceptor channel
            SpotPairs = getSpotPairs(Coords.Movie1,Coords.Movie2Translated,Args.DistanceCutoff);
            SpotPairs.Coords2 = cat(2,SpotPairs.Coords2(:,1)+TranslationalOffset.X,SpotPairs.Coords2(:,2)+TranslationalOffset.Y);
            
            % Construct and apply transformation to acceptor channel image
            app.Results.Tform = fitgeotrans(SpotPairs.Coords2,SpotPairs.Coords1,'polynomial',3);
            ra = imref2d(size(AvgImage.Movie1));
            AvgImage.Movie2Corrected = imwarp(AvgImage.Movie2,app.Results.Tform,'OutputView',ra);
            
            % Get bead coordinates in registered acceptor, find pairs, and plot overlay with donor bead coordinates
            Coords.Movie2Corrected = findSpots(AvgImage.Movie2Corrected,Args.Threshold,10,Args.CircularityCutoff,Args.SpotMethod);
            SpotPairsCorrected = getSpotPairs(Coords.Movie1,Coords.Movie2Corrected,Args.DistanceCutoff);
            
            app.Results.AvgImage = AvgImage;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OverlayOriginal = uiaxes(app.UIFigure);
            zlabel(app.OverlayOriginal, 'Z')
            app.OverlayCorrected = uiaxes(app.UIFigure);
            zlabel(app.OverlayCorrected, 'Z')
            app.OverlayCorrected.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DonorAcceptorOriginalLabel = uilabel(app.UIFigure);
            app.DonorAcceptorOriginalLabel.Text = 'Donor + Acceptor (Original)';
            app.DonorAcceptorRegisteredLabel = uilabel(app.UIFigure);
            app.DonorAcceptorRegisteredLabel.Text = 'Donor + Acceptor (Registered)';
            app.ChooseDonorButton = uibutton(app.UIFigure, 'push');
            app.ChooseDonorButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDonorButtonPushed, true);
            app.ChooseDonorButton.Text = 'Choose...';
            app.ChooseAcceptorButton = uibutton(app.UIFigure, 'push');
            app.ChooseAcceptorButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseAcceptorButtonPushed, true);
            app.ChooseAcceptorButton.Text = 'Choose...';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogBox = uitextarea(app.UIFigure);
            app.DonorBeadImageLabel = uilabel(app.UIFigure);
            app.DonorBeadImageLabel.Tooltip = {'If using a movie with Donor + Acceptor combined, load it as Donor Bead File'};
            app.DonorBeadImageLabel.Text = 'Donor Bead Image';
            app.AcceptorBeadImageLabel = uilabel(app.UIFigure);
            app.AcceptorBeadImageLabel.Tooltip = {'If using a single TIF file with both Donor and Acceptor, LEAVE THIS BLANK'};
            app.AcceptorBeadImageLabel.Text = 'Acceptor Bead Image';
            app.DonorFileName = uieditfield(app.UIFigure, 'text');
            app.AcceptorFileName = uieditfield(app.UIFigure, 'text');
            app.DonorAndAcceptorCombined = uicheckbox(app.UIFigure);
            app.DonorAndAcceptorCombined.Text = 'Donor and Acceptor in Same Image';
            app.GetBeadMapButton = uibutton(app.UIFigure, 'push');
            app.GetBeadMapButton.ButtonPushedFcn = createCallbackFcn(app, @GetBeadMapButtonPushed, true);
            app.GetBeadMapButton.Text = 'Get Bead Map';
            app.ChooseDonorButton_3 = uibutton(app.UIFigure, 'push');
            app.ChooseDonorButton_3.Enable = 'off';
            app.ChooseDonorButton_3.Text = 'Save Bead Map File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeadMapperSimple

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end